classdef ROIvsOdorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIvsOdorGUIUIFigure         matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadResponseMatrixFileMenu   matlab.ui.container.Menu
        LoadROIPositionsFileMenu     matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        ToolsMenu                    matlab.ui.container.Menu
        UnderConstructionMenu        matlab.ui.container.Menu
        LoadORdataFileButton         matlab.ui.control.Button
        OdorListListBoxLabel         matlab.ui.control.Label
        OdorListListBox              matlab.ui.control.ListBox
        ROIListListBox               matlab.ui.control.ListBox
        ROIListListBoxLabel          matlab.ui.control.Label
        SortOdorsByDropDownLabel     matlab.ui.control.Label
        SortOdorsByDropDown          matlab.ui.control.DropDown
        SortROIsByDropDownLabel      matlab.ui.control.Label
        SortROIsByDropDown           matlab.ui.control.DropDown
        MatrixScalingDropDownLabel   matlab.ui.control.Label
        MatrixScalingDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel        matlab.ui.control.Label
        ColormapDropDown             matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        SelectedOdorDropDownLabel    matlab.ui.control.Label
        SelectedOdorDropDown         matlab.ui.control.DropDown
        SelectedROIDropDownLabel     matlab.ui.control.Label
        SelectedROIDropDown          matlab.ui.control.DropDown
        LowThresholdofMaxLabel       matlab.ui.control.Label
        LowThresholdofMaxEditField   matlab.ui.control.NumericEditField
        UIAxes_OdorSort              matlab.ui.control.UIAxes
        UIAxes_ROISort               matlab.ui.control.UIAxes
        ShowSelectedOdorMapCheckBox  matlab.ui.control.CheckBox
        MetaDataButton               matlab.ui.control.Button
    end

    properties (Access = private)
        % ORdata = Struct containing: Maps, OdorList, ROIPos, ORdata.RespMatrix, MetaData
        ORdata = struct('RefImage',[],'RespMatrix',[],'OdorList',[],'ROIPos',[],'Maps',[],'Reg',[],'MetaData',[]);
        %RefImage: Average of all Map Baseline Images
        %RespMatrix:  %Matrix of Response Data - ROI #(row) vs Odor(column)
        %OdorList: cell with names of odors in RespMatrix (and Maps)
        %ROIpos: Table of ROI positions, ROI # vs Xpos, Ypos
        %Maps: difference map images - 3d matrix w/frames match odorlist order
        %Reg: global registration data - applied to roipos and maps (see RegistrationGUI_MWLab.m)
        bgimage; % standard background image
        bgreg; %registration data for bgimage
        scalestr = {'Absolute Scaling';'Row Min/Max';'Column Min/Max';'Chosen ROI Row';...
            'Chosen Odor Column';'Min/Max of All'}; %scaling options
        cmapstr = getcmaps; %colormaps
        settings; %settings
    end
    
    methods (Access = private)
        function loadfiles(app)
            %load Odor Response File
            if ~isempty(app.settings); lastdir = app.settings.lastdir; else; lastdir = pwd; end
            [fname,fpath] = uigetfile([lastdir '/*.mwlab_ORdata'],'Select Odor Response File');
            tmp = load(fullfile(fpath,fname),'-mat', 'ORdata');
            app.ORdata = tmp.ORdata; clear tmp;
            app.settings.lastdir = fpath;
            app.OdorListListBox.Items = app.ORdata.OdorList;
            app.OdorListListBox.Value = app.ORdata.OdorList;
            app.SelectedOdorDropDown.Items = app.ORdata.OdorList;
            %update roi list listbox
            roistr = '';
            for r = 1:size(app.ORdata.RespMatrix,1)
                roistr{r} = num2str(r);
            end
            app.ROIListListBox.Items = roistr; app.ROIListListBox.Value = roistr;
            app.SelectedROIDropDown.Items = roistr;
            updateplots(app);
        end
        function updateplots(app)
            %update roi locations plot
            cla(app.UIAxes); app.showbgimage; hold(app.UIAxes,'on');
            cla(app.UIAxes_OdorSort); hold(app.UIAxes_OdorSort,'on');
            cla(app.UIAxes_ROISort); hold(app.UIAxes_ROISort,'on');
            rois = zeros(numel(app.ROIListListBox.Value),1);
            %plot roi contours
            rr = str2double(app.SelectedROIDropDown.Value);
            for r = 1:numel(app.ROIListListBox.Value)
                rois(r)=str2double(app.ROIListListBox.Value{r});
                if rois(r) ~= rr
                    plot(app.UIAxes,app.ORdata.ROIPos.Xpos(r),app.ORdata.ROIPos.Ypos(r),'ok');
                end
            end
            plot(app.UIAxes,app.ORdata.ROIPos.Xpos(rr),app.ORdata.ROIPos.Ypos(rr),'or');
            rois = sort(rois); %otherwise, order may depend on ctrl-click sequence
            app.UIAxes.DataAspectRatio = [1 1 1];
            %update ROI vs Odor plot
            odorIDs = [];
            for o = 1:numel(app.OdorListListBox.Value)
                tmpmatch = find(cellfun(@(X)strcmp(app.OdorListListBox.Value{o},X),app.OdorListListBox.Items));
                odorIDs = union(odorIDs,tmpmatch);
            end
            odorIDs=sort(odorIDs);
            tmpdata = app.ORdata.RespMatrix(rois,odorIDs);
            %low threshold
            if ~isempty(app.LowThresholdofMaxEditField.Value)
                thresh = max(tmpdata(:))*app.LowThresholdofMaxEditField.Value/100;
            else
                thresh = min(tmpdata(:));
            end
            tmpdata(tmpdata<thresh)=thresh;
            %odor order
            odorsortval = zeros(length(odorIDs),1); odoridx = zeros(length(odorIDs),1);
            switch app.SortOdorsByDropDown.Value
                case app.SortOdorsByDropDown.Items{1} %odor id
                    XLABEL = 'Odor - sorted by Odor ID (bottom to top)';
                    odorsortval = odorIDs;
                    [odorsortval,odoridx] = sort(odorsortval);
                case app.SortOdorsByDropDown.Items{2} %Sparseness (relative to rois)
                    if length(rois)<2; app.SortOdorsByDropDown.Value = app.SortOdorsByDropDown.Items{1}; app.updateplots; return; end
                    XLABEL = 'Odor - sorted by Sparseness (high to low)';
                    odorsortval = zeros(length(odorIDs),1);
                    for o = 1:size(tmpdata,2)
                        odorsortval(o) = (1- (sum(tmpdata(:,o)./length(rois))^2/sum(tmpdata(:,o).^2./length(rois))) )/(1-1/length(rois));
                    end
                    [odorsortval,odoridx] = sort(odorsortval,'descend'); %assignin('base','osness',osness); assignin('base','odoridx',odoridx);
                case app.SortOdorsByDropDown.Items{3} %Correlation Coefficient vs selected odor
                    XLABEL = sprintf('Odor - sorted by Correlation Coefficient (vs %s, high to low)',app.SelectedOdorDropDown.Value);
                    sel_odor = find(cellfun(@(x) strcmp(x,app.SelectedOdorDropDown.Value),app.SelectedOdorDropDown.Items));
                    for o = 1:size(tmpdata,2)
                        odorsortval(o) = corr(app.ORdata.RespMatrix(rois,sel_odor),tmpdata(:,o));
                    end
                    [odorsortval,odoridx] = sort(odorsortval,'descend');
                case app.SortOdorsByDropDown.Items{4} %Response to selected ROI
                    XLABEL = sprintf('Odor - sorted by Response to Selected ROI (high to low)');
                    sel_roi = str2double(app.SelectedROIDropDown.Value);
                    for o = 1:size(tmpdata,2)
                        odorsortval(o) = app.ORdata.RespMatrix(sel_roi,odorIDs(o));
                    end
                    [odorsortval,odoridx] = sort(odorsortval,'descend');
                case app.SortOdorsByDropDown.Items{5}
                    %under construstion - load LUT
                    disp('ERROR: ODOR LUT IS NOT CODED');
                    app.SortOdorsByDropDown.Value = app.SortOdorsByDropDown.Items{1}; updateplots(app); return;
            end
            for o=1:length(odorsortval)
                plot(app.UIAxes_OdorSort,o,odorsortval(o),'.k');
            end
            if length(odorsortval)>1; app.UIAxes_OdorSort.YTick = [min(odorsortval) max(odorsortval)]; end
            app.UIAxes_OdorSort.XLim = [1-.5 length(odorsortval)+.5]; app.UIAxes_OdorSort.XTick = [1 5:5:length(odorsortval)];
            %roi sort order
            roisortval = zeros(length(rois),1); roiidx = zeros(length(rois),1);
            switch app.SortROIsByDropDown.Value
                case app.SortROIsByDropDown.Items{1} %roi id
                    YLABEL = 'ROI - sorted by ROI Index (bottom to top)';
                    roisortval = rois;
                    [roisortval,roiidx] = sort(roisortval);
                case app.SortROIsByDropDown.Items{2} %Sparseness (relative to odors)
                    YLABEL = 'ROI - sorted by Spartness (low to high)';
                    roisortval = zeros(length(rois),1);
                    for r = 1:size(tmpdata,1)
                        roisortval(r) = (1- (sum(tmpdata(r,:)./length(odorIDs))^2/sum(tmpdata(r,:).^2./length(odorIDs))) )/(1-1/length(odorIDs));
                    end
                    [roisortval,roiidx] = sort(roisortval,'descend'); %assignin('base','rsness',rsness); assignin('base','roiidx',roiidx);
                case app.SortROIsByDropDown.Items{3} %Correlation Coefficient
                    YLABEL = sprintf('ROI - sorted by Correlation Coefficient (vs ROI %s, low to high)',app.SelectedROIDropDown.Value);
                    sel_roi = str2double(app.SelectedROIDropDown.Value);
                    for r = 1:size(tmpdata,1)
                        roisortval(r) = corr(app.ORdata.RespMatrix(sel_roi,odorIDs)',tmpdata(r,:)');
                    end
                    [roisortval,roiidx] = sort(roisortval,'descend'); %assignin('base','roicc',roicc); assignin('base','roiidx',roiidx);
                case app.SortROIsByDropDown.Items{4} %Position vs reference roi
                    YLABEL = sprintf('ROI - sorted by Position (Distance from ROI %s, far to close)',app.SelectedROIDropDown.Value);
                    sel_roi = str2double(app.SelectedROIDropDown.Value);
                    for r = 1:length(rois)
                        roisortval(r) = sqrt( (app.ORdata.ROIPos.Xpos(rois(r))-app.ORdata.ROIPos.Xpos(sel_roi))^2 + (app.ORdata.ROIPos.Ypos(rois(r))-app.ORdata.ROIPos.Ypos(sel_roi))^2 );
                    end
                    [roisortval,roiidx] = sort(roisortval); %assignin('base','roidist',roidist); assignin('base','roiidx',roiidx);
                case app.SortROIsByDropDown.Items{5} %Position distance from origin
                    YLABEL = sprintf('ROI - sorted by Position (Distance from Origin, far to close)');
                    for r = 1:length(rois)
                        roisortval(r) = sqrt( app.ORdata.ROIPos.Xpos(rois(r))^2 + app.ORdata.ROIPos.Ypos(rois(r))^2 );
                    end
                    [roisortval,roiidx] = sort(roisortval); %assignin('base','roidist',roidist); assignin('base','roiidx',roiidx);
                case app.SortROIsByDropDown.Items{6} %Response to selected Odor
                    YLABEL = sprintf('ROI - sorted by Response to Selected Odor (low to high)');
                    sel_odor = find(cellfun(@(X) strcmp(app.SelectedOdorDropDown.Value,X),app.SelectedOdorDropDown.Items));
                    for r = 1:length(rois)
                        roisortval(r) = app.ORdata.RespMatrix(rois(r),sel_odor);
                    end
                    [roisortval,roiidx] = sort(roisortval,'descend');
            end
            for r=1:length(roisortval)
                plot(app.UIAxes_ROISort,roisortval(r),r,'.k');
            end
            if length(roisortval)>1; app.UIAxes_ROISort.XTick = [min(roisortval) max(roisortval)]; end
            app.UIAxes_ROISort.YLim = [0 length(roisortval)+1]; app.UIAxes_ROISort.YDir = 'reverse';
            app.UIAxes_ROISort.YTick = [1 10:10:length(roisortval)];
            %matrix scaling
            data = zeros(size(tmpdata));
            switch app.MatrixScalingDropDown.Value
                case app.scalestr{1} %Absolute
                    data = tmpdata; 
                case app.scalestr{2} %Row Min-Max
                    for r = 1:size(tmpdata,1)
                        data(r,:) = ( tmpdata(r,:)-min(tmpdata(r,:)) )./range(tmpdata(r,:));
                    end
                case app.scalestr{3} %Column Min-Max
                    for o = 1:size(tmpdata,2)
                        data(:,o) = ( tmpdata(:,o) - min(tmpdata(:,o)) )./range(tmpdata(:,o));
                    end
                case app.scalestr{4} % Chosen ROI row
                    sel_roi = str2double(app.SelectedROIDropDown.Value);
                    rmin = min(app.ORdata.RespMatrix(sel_roi,odorIDs)); rmax = max(app.ORdata.RespMatrix(sel_roi,odorIDs));
                    data = tmpdata;
                    data(data<rmin)=rmin; data(data>rmax)=rmax;
                    for r = 1:size(data,1)
                        data(r,:) = ( data(r,:)-rmin )./ (rmax-rmin);
                    end
                case app.scalestr{5} %Max/Min of Chosen Odor column
                    sel_odor = find(cellfun(@(X) strcmp(app.SelectedOdorDropDown.Value,X),app.SelectedOdorDropDown.Items));
                    omin = min(app.ORdata.RespMatrix(rois,sel_odor)); omax = max(app.ORdata.RespMatrix(rois,sel_odor));
                    data = tmpdata; data(data<omin)=omin; data(data>omax)=omax;
                    for o = 1:size(data,2)
                        data(:,o) = ( data(:,o)-omin )./ (omax-omin);
                    end
                case app.scalestr{6} %Max/Min of All
                    data = ( tmpdata-min(tmpdata(:)) )./range(tmpdata(:));
            end
            imagesc(app.UIAxes_2,data(roiidx,odoridx)); colorbar(app.UIAxes_2);
            app.UIAxes_2.XLabel.String = XLABEL; app.UIAxes_2.YLabel.String = YLABEL;
            app.UIAxes_2.YLim = [0.5 length(rois)+.5]; app.UIAxes_2.XLim = [0.5 length(odorIDs)+.5];
            app.UIAxes_2.XTick = 1:length(odorIDs);
            app.UIAxes_2.XTickLabel = app.OdorListListBox.Items(odorIDs(odoridx));
            app.UIAxes_2.XTickLabelRotation = -90;
            if length(rois)<50
                app.UIAxes_2.YTick = 1:size(rois);
                app.UIAxes_2.YTickLabel = rois(roiidx);
            else
                app.UIAxes_2.YTick = [];
            end
            %note gca not supported by appdesigner apps, so need to call nawhimar_auto or automax directly w/clim
            if strcmp(app.ColormapDropDown.Value,'nawhimar_auto')
                tmpmap = nawhimar_auto(256,app.UIAxes_2.CLim);
                colormap(app.UIAxes_2,tmpmap);
            elseif strcmp(app.ColormapDropDown.Value,'nawhimar_automax')
                tmpmap = nawhimar_automax(256,app.UIAxes_2.CLim);
                colormap(app.UIAxes_2,tmpmap);
            else
                colormap(app.UIAxes_2,[app.ColormapDropDown.Value '(256)']);
            end
            assignin('base','roirank',roiidx); assignin('base','roirankvalues',roisortval);
            assignin('base','odorrank',odoridx); assignin('base','odorrankvalues',odorsortval);
            app.UIAxes_ROISort.Position = [app.UIAxes_ROISort.Position(1) app.UIAxes_2.InnerPosition(2) ...
                app.UIAxes_ROISort.Position(3) app.UIAxes_2.InnerPosition(4)];
        end
    
        function showbgimage(app)
            assignin('base','ORdata',app.ORdata);
            if app.ShowSelectedOdorMapCheckBox.Value && ~isempty(app.ORdata.Maps)
                oo = cellfun(@(x) strcmp(x,app.SelectedOdorDropDown.Value),app.SelectedOdorDropDown.Items);
                bgim = app.ORdata.Maps{oo};
            else
                app.ShowSelectedOdorMapCheckBox.Value = 0;
                bgim = app.ORdata.RefImage;
            end
            if ~isempty(app.ORdata.Reg)
                bgim = flipud(bgim); %normally, images are displayed on an axes w/ydir='reverse'
                bgpixsize = app.ORdata.Reg.pixelsize;
                bgzoom = app.ORdata.Reg.zoom;
                if ~isempty(bgzoom) && isfinite(str2double(bgzoom))
                    bgpixsize = bgpixsize./str2double(bgzoom);
                end
                bgxpos = app.ORdata.Reg.xpos;
                bgypos = app.ORdata.Reg.ypos;
                bgtheta = app.ORdata.Reg.rotation;
%                 bgflipy = app.ORdata.Reg.flipy;
                %do stretch first, then rotate later
                if bgpixsize(1) < bgpixsize(2) %stretch the image in Y to make pixels same size
                    bgim = imresize(bgim,[size(bgim,1)*bgpixsize(2)/bgpixsize(1) size(bgim,2)]);
                    bgpixsize(2)=bgpixsize(1);
                elseif bgpixsize(2) < bgpixsize(1) %stretch in X (currently not used for any of our images)
                    bgim = imresize(bgim,[size(bgim,1) size(bgim,2)*bgpixsize(1)/bgpixsize(2)]);
                    bgpixsize(1)=bgpixsize(2);
                end            
%                 if bgflipy; bgim = flipud(bgim); bgypos = -bgypos; bgtheta = -bgtheta; end %flip-y
                [bgrows,bgcols] = size(bgim);
                bgxd = [-.5*bgcols*bgpixsize(1) .5*bgcols*bgpixsize(1)]+bgxpos;
                bgyd = [-.5*bgrows*bgpixsize(2) .5*bgrows*bgpixsize(2)]+bgypos;
                if bgtheta~=0
                    oldsize = size(bgim);
                    tform = affine2d([cosd(bgtheta) sind(bgtheta) 0; -sind(bgtheta) cosd(bgtheta) 0; 0 0 1]);
                    bgim = fillmissing(bgim,'constant',min(bgim(:)));
                    bgim = imwarp(bgim,tform,'FillValues',nan);
                    ratios = size(bgim)./oldsize;
                    bgxd = [mean(bgxd)-diff(bgxd)/2*ratios(2) mean(bgxd)+diff(bgxd)/2*ratios(2)];
                    bgyd = [mean(bgyd)-diff(bgyd)/2*ratios(1) mean(bgyd)+diff(bgyd)/2*ratios(1)];
                end
%                 bgim = imrotate(bgim,90);
                %imagesc(app.UIAxes,bgim,'Ydata',bgyd,'XData',flip(bgxd));
                imagesc(app.UIAxes,bgim,'Ydata',bgyd,'XData',bgxd);
                app.UIAxes.YDir = 'normal';
                %app.UIAxes.XLim = [0 2500]; app.UIAxes.YLim = [0  2500]; app.UIAxes.YDir = 'normal';
                app.UIAxes.XLim = [-2500 2500]; app.UIAxes.YLim = [-1000 3600];
                app.UIAxes.XLabel.String = 'Lateral Distance'; app.UIAxes.YLabel.String = 'Anterior Distance';
            else
                %bgim = imrotate(bgim,90);
                imagesc(app.UIAxes,bgim);
                app.UIAxes.YDir = 'reverse';
                app.UIAxes.YLim = [0.5 size(bgim,1)+0.5]; app.UIAxes.XLim = [0.5 size(bgim,2)+0.5];
                app.UIAxes.XLabel.String = []; app.UIAxes.YLabel.String = [];
            end
            if strcmp(app.ColormapDropDown.Value,'nawhimar_auto')
                tmpmap = nawhimar_auto(256,app.UIAxes.CLim);
                colormap(app.UIAxes,tmpmap);
            elseif strcmp(app.ColormapDropDown.Value,'nawhimar_automax')
                tmpmap = nawhimar_automax(256,app.UIAxes.CLim);
                colormap(app.UIAxes,tmpmap);
            else
                colormap(app.UIAxes,[app.ColormapDropDown.Value '(256)']);
            end
        end

    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %set scaling string
            app.MatrixScalingDropDown.Items = app.scalestr; app.MatrixScalingDropDown.Value = app.scalestr{1};
            %set colormap values
            app.ColormapDropDown.Items = app.cmapstr; app.ColormapDropDown.Value = app.cmapstr{1};
            %read settings file
            setfname = mfilename('fullpath');
            if isfile([setfname '.settings']); load([setfname '.settings'],'-mat','prefs'); app.settings = prefs;
            else; app.settings.lastdir = pwd;
            end
            if nargin>1
                app.ORdata = varargin{1};
                %update odor list listbox
                app.OdorListListBox.Items = app.ORdata.OdorList;
                app.OdorListListBox.Value = app.ORdata.OdorList;
                app.SelectedOdorDropDown.Items = app.ORdata.OdorList;
                %update roi list listbox
                roistr = '';
                for r = 1:size(app.ORdata.RespMatrix,1)
                    roistr{r} = num2str(r);
                end
                app.ROIListListBox.Items = roistr;
                app.ROIListListBox.Value = roistr;
                app.SelectedROIDropDown.Items = roistr;
                updateplots(app);
            end
        end

        % Button pushed function: LoadORdataFileButton
        function LoadORdataFileButtonPushed(app, event)
            app.loadfiles;
        end

        % Value changed function: OdorListListBox
        function OdorListListBoxValueChanged(app, event)
            app.updateplots;
        end

        % Value changed function: ROIListListBox
        function ROIListListBoxValueChanged(app, event)
            app.updateplots;
        end

        % Value changed function: MatrixScalingDropDown
        function MatrixScalingDropDownValueChanged(app, event)
            app.updateplots;
        end

        % Value changed function: SortOdorsByDropDown
        function SortOdorsByDropDownValueChanged(app, event)
            app.updateplots;            
        end

        % Value changed function: SortROIsByDropDown
        function SortROIsByDropDownValueChanged(app, event)
            app.updateplots;            
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.updateplots;
        end

        % Value changed function: LowThresholdofMaxEditField
        function LowThresholdofMaxEditFieldValueChanged(app, event)
            app.updateplots;
        end

        % Value changed function: SelectedROIDropDown
        function SelectedROIDropDownValueChanged(app, event)
            app.updateplots;            
        end

        % Value changed function: SelectedOdorDropDown
        function SelectedOdorDropDownValueChanged(app, event)
            app.updateplots;
        end

        % Close request function: ROIvsOdorGUIUIFigure
        function ROIvsOdorGUIUIFigureCloseRequest(app, event)
            %write settings file
            setfname = mfilename('fullpath');
            prefs = app.settings;
            save([setfname '.settings'],'-mat','prefs');
            delete(app);
        end

        % Value changed function: ShowSelectedOdorMapCheckBox
        function ShowSelectedOdorMapCheckBoxValueChanged(app, event)
            app.updateplots            
        end

        % Button pushed function: MetaDataButton
        function MetaDataButtonPushed(app, event)
            ftmp = findobj('type','figure','Name','MetaData');
            if isempty(ftmp); ftmp = figure('Name','MetaData'); end
            sel_odor = find(cellfun(@(X) strcmp(app.SelectedOdorDropDown.Value,X),app.SelectedOdorDropDown.Items));
            metastr = sprintf('%s\n',app.ORdata.MetaData{sel_odor});
            ftxt = findobj(ftmp,'type','text');
            if isempty(ftxt)
                uicontrol(ftmp,'style','text','String',metastr,'Units','Normalized','Position',[0 0 1 1]);
            else
                ftxt.String = metastr;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIvsOdorGUIUIFigure = uifigure;
            app.ROIvsOdorGUIUIFigure.Name = 'ROI vs Odor GUI';
            app.ROIvsOdorGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ROIvsOdorGUIUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ROIvsOdorGUIUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadResponseMatrixFileMenu = uimenu(app.FileMenu);
            app.LoadResponseMatrixFileMenu.Text = 'Load Response Matrix File';
            app.LoadROIPositionsFileMenu = uimenu(app.FileMenu);
            app.LoadROIPositionsFileMenu.Text = 'Load ROI Positions File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.ROIvsOdorGUIUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.UnderConstructionMenu = uimenu(app.ToolsMenu);
            app.UnderConstructionMenu.Text = 'Under Construction';
            app.LoadORdataFileButton = uibutton(app.ROIvsOdorGUIUIFigure, 'push');
            app.LoadORdataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadORdataFileButtonPushed, true);
            app.LoadORdataFileButton.Text = 'Load "ORdata" File';
            app.OdorListListBoxLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.OdorListListBoxLabel.Text = 'Odor List';
            app.OdorListListBox = uilistbox(app.ROIvsOdorGUIUIFigure);
            app.OdorListListBox.Multiselect = 'on';
            app.OdorListListBox.ValueChangedFcn = createCallbackFcn(app, @OdorListListBoxValueChanged, true);
            app.OdorListListBox.Value = {'Item 1'};
            app.ROIListListBox = uilistbox(app.ROIvsOdorGUIUIFigure);
            app.ROIListListBox.Multiselect = 'on';
            app.ROIListListBox.ValueChangedFcn = createCallbackFcn(app, @ROIListListBoxValueChanged, true);
            app.ROIListListBox.Value = {'Item 1'};
            app.ROIListListBoxLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.ROIListListBoxLabel.Text = {'ROI List'; ''};
            app.SortOdorsByDropDownLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.SortOdorsByDropDownLabel.Text = 'Sort Odors By:';
            app.SortOdorsByDropDown = uidropdown(app.ROIvsOdorGUIUIFigure);
            app.SortOdorsByDropDown.Items = {'Odor ID', 'Sparseness', 'Correlation Coefficient', 'Response to Selected ROI', 'Similarity LUT'};
            app.SortOdorsByDropDown.ValueChangedFcn = createCallbackFcn(app, @SortOdorsByDropDownValueChanged, true);
            app.SortOdorsByDropDown.Value = 'Odor ID';
            app.SortROIsByDropDownLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.SortROIsByDropDownLabel.Text = 'Sort ROIs By:';
            app.SortROIsByDropDown = uidropdown(app.ROIvsOdorGUIUIFigure);
            app.SortROIsByDropDown.Items = {'ROI ID', 'Sparseness', 'Correlation Coefficient', 'Proximity to Reference ROI', 'Position (Distance from Origin)', 'Response to Selected Odor'};
            app.SortROIsByDropDown.ValueChangedFcn = createCallbackFcn(app, @SortROIsByDropDownValueChanged, true);
            app.SortROIsByDropDown.Value = 'ROI ID';
            app.MatrixScalingDropDownLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.MatrixScalingDropDownLabel.Text = 'Matrix Scaling:';
            app.MatrixScalingDropDown = uidropdown(app.ROIvsOdorGUIUIFigure);
            app.MatrixScalingDropDown.Items = {'Absolute', 'Row Min/Max', 'Column Min/Max', 'Max/Min of All', 'Min/Max of chosen odor/ROI', ''};
            app.MatrixScalingDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixScalingDropDownValueChanged, true);
            app.MatrixScalingDropDown.Value = 'Absolute';
            app.ColormapDropDownLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap:';
            app.ColormapDropDown = uidropdown(app.ROIvsOdorGUIUIFigure);
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = {};
            app.UIAxes = uiaxes(app.ROIvsOdorGUIUIFigure);
            title(app.UIAxes, 'ROI positions')
            xlabel(app.UIAxes, 'Lateral Distance')
            ylabel(app.UIAxes, 'Anterior Distance')
            app.UIAxes_2 = uiaxes(app.ROIvsOdorGUIUIFigure);
            title(app.UIAxes_2, 'Response Matrix')
            xlabel(app.UIAxes_2, 'Odor ID')
            ylabel(app.UIAxes_2, 'ROI ID')
            app.SelectedOdorDropDownLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.SelectedOdorDropDownLabel.Text = 'Selected Odor';
            app.SelectedOdorDropDown = uidropdown(app.ROIvsOdorGUIUIFigure);
            app.SelectedOdorDropDown.Items = {'Item 1', ''};
            app.SelectedOdorDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedOdorDropDownValueChanged, true);
            app.SelectedOdorDropDown.Value = 'Item 1';
            app.SelectedROIDropDownLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.SelectedROIDropDownLabel.Text = 'Selected ROI';
            app.SelectedROIDropDown = uidropdown(app.ROIvsOdorGUIUIFigure);
            app.SelectedROIDropDown.Items = {'Item 1', ''};
            app.SelectedROIDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedROIDropDownValueChanged, true);
            app.SelectedROIDropDown.Value = 'Item 1';
            app.LowThresholdofMaxLabel = uilabel(app.ROIvsOdorGUIUIFigure);
            app.LowThresholdofMaxLabel.Text = 'Low Threshold (% of Max)';
            app.LowThresholdofMaxEditField = uieditfield(app.ROIvsOdorGUIUIFigure, 'numeric');
            app.LowThresholdofMaxEditField.Limits = [0 100];
            app.LowThresholdofMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LowThresholdofMaxEditFieldValueChanged, true);
            app.UIAxes_OdorSort = uiaxes(app.ROIvsOdorGUIUIFigure);
            app.UIAxes_OdorSort.XTick = [];
            app.UIAxes_OdorSort.YTick = [];
            app.UIAxes_ROISort = uiaxes(app.ROIvsOdorGUIUIFigure);
            app.UIAxes_ROISort.XTick = [];
            app.UIAxes_ROISort.YTick = [];
            app.ShowSelectedOdorMapCheckBox = uicheckbox(app.ROIvsOdorGUIUIFigure);
            app.ShowSelectedOdorMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSelectedOdorMapCheckBoxValueChanged, true);
            app.ShowSelectedOdorMapCheckBox.Text = 'Show Selected Odor Map';
            app.MetaDataButton = uibutton(app.ROIvsOdorGUIUIFigure, 'push');
            app.MetaDataButton.ButtonPushedFcn = createCallbackFcn(app, @MetaDataButtonPushed, true);
            app.MetaDataButton.Text = 'MetaData';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ROIvsOdorGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIvsOdorGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIvsOdorGUIUIFigure)
        end
    end
end